classdef DC_motor_contro_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LoadtorqEditField       matlab.ui.control.NumericEditField
        LoadtorqEditFieldLabel  matlab.ui.control.Label
        KdEditField             matlab.ui.control.NumericEditField
        KdEditFieldLabel        matlab.ui.control.Label
        KiEditField             matlab.ui.control.NumericEditField
        KiEditFieldLabel        matlab.ui.control.Label
        KpEditField             matlab.ui.control.NumericEditField
        KpEditFieldLabel        matlab.ui.control.Label
        SpeedRefEditField       matlab.ui.control.NumericEditField
        SpeedRefEditFieldLabel  matlab.ui.control.Label
        RunSimulaButton         matlab.ui.control.Button
        simulationAxes          matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulaButton
        function RunSimulaButtonPushed(app, event)
       
    % Read values from GUI
    SpeedRef = app.SpeedRefEditField.Value;
Kp_pid = app.KpEditField.Value;
Ki_pid = app.KiEditField.Value;
Kd_pid = app.KdEditField.Value;
 T_load = app.LoadtorqEditField.Value;
% Send values to Simulink (base workspace)
assignin('base','SpeedRef', SpeedRef);
assignin('base','Kp_pid', Kp_pid);
assignin('base','Ki_pid', Ki_pid);
assignin('base','Kd_pid', Kd_pid);
assignin('base','T_load', T_load);
% Run Simulink model
simOut = sim('DC_Motor_PID'); 

% Retrieve outputs from workspace
% If saved as structure with time
omega_ts = evalin('base','out');        % out struct
omega_ts = omega_ts.omega;              % extract omega timeseries

current_ts = evalin('base','out');      % out struct
current_ts = current_ts.i_armature;    % extract current timeseries

% Extract data for plotting
t = omega_ts.Time;
omega = omega_ts.Data;

current = current_ts.Data;

% Plot speed
cla(app.UIAxes)
plot(app.UIAxes, t, omega, 'LineWidth', 2)
grid(app.UIAxes, 'on')
xlabel(app.UIAxes, 'Time (s)')
ylabel(app.UIAxes, 'Speed (rad/s)')
title(app.UIAxes, 'DC Motor Speed Response')
% Plot current
cla(app.UIAxes2)
plot(app.UIAxes2, t, current, 'LineWidth', 2)
grid(app.UIAxes2, 'on')
xlabel(app.UIAxes2, 'Time (s)')
ylabel(app.UIAxes2, 'Current (A)')
title(app.UIAxes2, 'Armature Current Response')
%% --- Combined Plot on SimulationAxes ---

% If SpeedRef is constant, create a vector same size as t
ref_speed_vec = SpeedRef*ones(size(t));

% Clear new axes
cla(app.simulationAxes)

% Plot all three signals
plot(app.simulationAxes, t, ref_speed_vec, 'k--', 'LineWidth', 2)   % Reference speed
hold(app.simulationAxes, 'on')
plot(app.simulationAxes, t, omega, 'b', 'LineWidth', 2)             % Motor speed
plot(app.simulationAxes, t, current, 'r', 'LineWidth', 2)           % Armature current
hold(app.simulationAxes, 'off')

% Customize plot
xlabel(app.simulationAxes,'Time (s)')
ylabel(app.simulationAxes,'Signal')
title(app.simulationAxes,'DC Motor: Reference, Speed & Current')
legend(app.simulationAxes,'Reference \omega_{ref}','Motor \omega','I_{armature}')
grid(app.simulationAxes,'on')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Motor Speed response ')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Speed(rad/s)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Armature current')
            xlabel(app.UIAxes2, 'Time(s)')
            ylabel(app.UIAxes2, 'current(A)')
            zlabel(app.UIAxes2, 'Z')
            app.simulationAxes = uiaxes(app.UIFigure);
            title(app.simulationAxes, 'DC Motor: Reference, Speed & Current''')
            xlabel(app.simulationAxes, 'Time (s)')
            ylabel(app.simulationAxes, 'Signal')
            zlabel(app.simulationAxes, 'Z')
            app.RunSimulaButton = uibutton(app.UIFigure, 'push');
            app.RunSimulaButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulaButtonPushed, true);
            app.RunSimulaButton.Text = 'Run Simula';
            app.SpeedRefEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedRefEditFieldLabel.Text = 'SpeedRef';
            app.SpeedRefEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedRefEditField.Value = 100;
            app.KpEditFieldLabel = uilabel(app.UIFigure);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.UIFigure, 'numeric');
            app.KpEditField.Value = 30;
            app.KiEditFieldLabel = uilabel(app.UIFigure);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.UIFigure, 'numeric');
            app.KiEditField.Value = 40;
            app.KdEditFieldLabel = uilabel(app.UIFigure);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.UIFigure, 'numeric');
            app.KdEditField.Value = 2;
            app.LoadtorqEditFieldLabel = uilabel(app.UIFigure);
            app.LoadtorqEditFieldLabel.Text = 'Load torq';
            app.LoadtorqEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadtorqEditField.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DC_motor_contro_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end